classdef MIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Switch                      matlab.ui.control.Switch
        ImageFrame                  matlab.ui.container.Panel
        inputimg                    matlab.ui.control.Image
        fft                         matlab.ui.control.Image
        fftshift                    matlab.ui.control.Image
        outputimg                   matlab.ui.control.Image
        BackButton                  matlab.ui.control.Button
        AnalyseImageButton          matlab.ui.control.Button
        DensityEditFieldLabel       matlab.ui.control.Label
        DensityEditField            matlab.ui.control.NumericEditField
        ResetButton_2               matlab.ui.control.Button
        Noiseframe                  matlab.ui.container.Panel
        BackButton_2                matlab.ui.control.Button
        AnalyseNoiseButton          matlab.ui.control.Button
        ValueEditFieldLabel         matlab.ui.control.Label
        ValueEditField              matlab.ui.control.NumericEditField
        FilterButton                matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        ResetButton_3               matlab.ui.control.Button
        OutputA                     matlab.ui.control.UIAxes
        Fre1                        matlab.ui.control.UIAxes
        Fre2                        matlab.ui.control.UIAxes
        MainFrame                   matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        FilteringTypeDropDownLabel  matlab.ui.control.Label
        FilteringTypeDropDown       matlab.ui.control.DropDown
        OpenFileButton              matlab.ui.control.Button
        ProcessButton               matlab.ui.control.Button
        StartRecordingButton        matlab.ui.control.Button
        PathTextArea                matlab.ui.control.TextArea
        ResetButton                 matlab.ui.control.Button
        InputImage                  matlab.ui.control.Image
        Inputaudioaxe               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       Path = 0
       AudPath = 0
       filter=0
       AudFile=0;
       
    end
    
    methods (Access = public)
        
        
        function [ImgIn,ImgFFt,ImgFs,ImgIFFt] = ImageProcess(app)
         ImgIn="";
      ImgFFt="";
      ImgFs="";
      ImgIFFt="";
      
             if exist('InputGray.jpeg','file')==2
                delete('InputGray.jpeg')
             end
              pause(1);
            if exist('FFTImg.jpeg','file')==2
                delete('FFTImg.jpeg')
            end
             pause(1);
            if exist('FsImg.jpeg','file')==2
                delete('FsImg.jpeg')
            end
             pause(1);
            if exist('IFFTImg.jpeg','file')==2
                delete('IFFTImg.jpeg')
            end
            
            pause(1);
            if(app.Path==0)
                app.MainFrame.Visible="on";
                app.ImageFrame.Visible="off";
                app.Noiseframe.Visible="off";
                app.PathTextArea.Value="Image isnt Selected or Path doesnt Exist";
            else
                img=imread(app.Path);
                  img=rgb2gray(img);
     img= imnoise(img,"salt & pepper",0.02);
     imwrite(img,'InputGray.jpeg','jpeg');
imgd=double(img);%image precision


F=fft2(imgd); %DFT of image	
F1=log(1+abs(F));
Fm=max(F1(:));
F1=im2uint8(F1/Fm);
imwrite(F1,'FFTImg.jpeg','jpeg');

g=fspecial('gaussian',size(imgd),app.DensityEditField.Value);
g=mat2gray(g);
Fs=fftshift(F); %Shifting spectrum to centre	
Fs=Fs.*g;
Fs1=log(1+abs(Fs));
Fsm=max(Fs(:));
Fs1=im2uint8(Fs1/Fsm);
imwrite(Fs1,'FsImg.jpeg','jpeg');

fimg=ifft2(Fs); %Inverse OFT 
F2=abs(fimg);
F2m=max(F2(:));
F2=F2/F2m;
imwrite(F2,'IFFTImg.jpeg','jpeg');

      ImgIn="InputGray.jpeg";
      ImgFFt="FFTImg.jpeg";
      ImgFs="FsImg.jpeg";
      ImgIFFt="IFFTImg.jpeg";

            end
          
        end
        function
         function ImgPath = Inputimg(app)
     [filename ,pathname] = uigetfile( '*.jpeg','Select Image ');
     filewithpath=strcat(pathname,filename) ;
     ImgPath=filewithpath;
     app.Path=ImgPath;
         end
         
        
         function AudioPath = InputAudio(app)
             [filename ,pathname] = uigetfile( '*.wav','Select your Audio ');
     filewithpath=strcat(pathname,filename) ;
     AudioPath=filewithpath;
     app.AudPath=AudioPath;
     app.AudFile=filename;
         end
         
        
         function [T,Freq,PSD1,PSD2,yFilter] = AudioProcess(app)
          app.filter=app.ValueEditField.Value;
           if(app.AudPath==0)
               
           else 
             [y,fs] = audioread(app.AudPath);
           
            info=audioinfo(app.AudPath);
            t = 0:seconds(1/fs):seconds(info.Duration);
            t = t(1:end-1);
            T=t;
            N=length(t);
yin=fft(y,N);
PSD = yin.*conj(yin)/N;
freq=1/(0.001*N)*(0:N);
L=1:floor(N/2);
Freq=freq(L);
PSD1=PSD(L);
ind=PSD>app.filter;
PSD=PSD.*ind;
PSD2=PSD(L);

yin=ind.*yin;
yfilt=ifft(yin);
yFilter=yfilt;
           end
             
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Inputaudioaxe.Visible="off";
            app.InputImage.Visible="off";
            app.Switch.Value="Off";
           app.MainFrame.Enable="off";
           app.inputimg.Visible="off";
           app.fft.Visible="off";
           app.fftshift.Visible="off";
           app.outputimg.Visible="off";
           app.DensityEditField.Value=20;
           
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if(app.FilteringTypeDropDown.Value=="Image Processing")
                app.MainFrame.Visible="off";
                app.Noiseframe.Visible="off";
                app.ImageFrame.Visible="on";
                
            elseif(app.FilteringTypeDropDown.Value=="Noise Flitering")
                 app.MainFrame.Visible="off";
                app.Noiseframe.Visible="on";
                app.ImageFrame.Visible="off";
            else
                
            end
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
             app.MainFrame.Visible="on";
                app.Noiseframe.Visible="off";
                app.ImageFrame.Visible="off";
                
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
             app.MainFrame.Visible="on";
                app.Noiseframe.Visible="off";
                app.ImageFrame.Visible="off";
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(value=="Off")
                app.MainFrame.Enable="off";
            elseif(value=="On")
                 app.MainFrame.Enable="on";
            end
        end

        % Button pushed function: AnalyseImageButton
        function AnalyseImageButtonPushed(app, event)

            app.fft.Visible="off";
            app.fftshift.Visible="off";
            app.outputimg.Visible="off";
            app.inputimg.Visible="off";
        if(app.Path==0)
              app.MainFrame.Visible="on";
                app.ImageFrame.Visible="off";
                app.Noiseframe.Visible="off";
                app.PathTextArea.Value="Image isnt Selected or Path doesnt Exist";
        else
           
             [ImgIn,ImgFFt,ImgFs,ImgIFFt] = app.ImageProcess ;
            figure
            subplot(221);
            imshow(imread(ImgIn));
            subplot(222);
            imshow(imread(ImgFFt));
            subplot(223);
            imshow(imread(ImgFs));
            subplot(224);
            imshow(imread(ImgIFFt));
            %Input Grayscale Image
            pause(1);
            
           
app.inputimg.ImageSource=ImgIn;
app.inputimg.Visible="on";
pause(1);
app.fft.ImageSource=ImgFFt;
app.fft.Visible="on";
pause(1);
app.fftshift.ImageSource=ImgFs;
app.fftshift.Visible="on";
pause(1);
app.outputimg.ImageSource=ImgIFFt;
app.outputimg.Visible="on";
        end
       
  
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
          if(app.FilteringTypeDropDown.Value=="Image Processing")
               app.Inputaudioaxe.Visible="off";
              path = app.Inputimg;
        app.InputImage.ImageSource=path;
         
            app.InputImage.Visible="on";
                
            elseif(app.FilteringTypeDropDown.Value=="Noise Flitering")
                app.InputImage.Visible="off";
                path =app.InputAudio;
                [y,fs]=audioread(path);
                info = audioinfo(path);
                t = 0:seconds(1/fs):seconds(info.Duration);
                t = t(1:end-1);
                plot(t,y,"Parent",app.Inputaudioaxe)
                app.Inputaudioaxe.XLabel.String= "Time";
                app.Inputaudioaxe.YLabel.String="Audio Signal";
                app.Inputaudioaxe.Title.String="Input Audio Signal";
                app.Inputaudioaxe.Visible="on";
                sound(y,fs);

          end
        end

        % Button pushed function: AnalyseNoiseButton
        function AnalyseNoiseButtonPushed(app, event)
           if(app.AudPath==0)
                app.MainFrame.Visible="on";
                app.ImageFrame.Visible="off";
                app.Noiseframe.Visible="off";
                app.PathTextArea.Value="Audio isnt Selected or Path doesnt Exist";
        
           else
                [T,Freq,PSD1,PSD2,yFilter] = AudioProcess(app);
                pause(1);
                plot(Freq,PSD1,"Parent",app.Fre1);
                app.Fre1.XLabel.String = "Frequencey";
                app.Fre1.YLabel.String = "Frequency Domain";
                app.Fre1.Title.String = "Fourier Transformation";
                
                
           end
            
            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
             if(app.AudPath==0)
                app.MainFrame.Visible="on";
                app.ImageFrame.Visible="off";
                app.Noiseframe.Visible="off";
                app.PathTextArea.Value="Audio isnt Selected or Path doesnt Exist";
        
              else
                [T,Freq,PSD1,PSD2,yFilter] = AudioProcess(app);
                 pause(1);
                plot(Freq,PSD2,"Parent",app.Fre2);
                app.Fre2.XLabel.String = "Frequencey";
                app.Fre2.YLabel.String = "Frequency Domain";
                app.Fre2.Title.String = "Fourier Transformation";
                
                pause(1);
                plot(T,yFilter,"Parent",app.OutputA);
                app.OutputA.XLabel.String = "Time";
                app.OutputA.YLabel.String = "Audio Signal";
                app.OutputA.Title.String = "Filtered Signal";  
             end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
              if(app.AudPath==0)
                app.MainFrame.Visible="on";
                app.ImageFrame.Visible="off";
                app.Noiseframe.Visible="off";
                app.PathTextArea.Value="Audio isnt Selected or Path doesnt Exist";
        
           else
                 [y,fs]=audioread(app.AudPath);
                 F=fs;
                  [T,Freq,PSD1,PSD2,yFilter] = AudioProcess(app);
                 app.AudFile=strrep(app.AudFile,".","Filterd.");
                audiowrite(app.AudFile,yFilter,F);
                sound(yFilter,F);
                
              end


        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
          
           while(true)
                if(app.StartRecordingButton.Text=="Start Recording")
                    
                global recObj
               recObj = audiorecorder ; %create object
                record(recObj); %start Recording
                
                app.PathTextArea.Value="Speak now .... ";
                app.StartRecordingButton.Text="Stop Recording";
                break;
                end
                
            if(app.StartRecordingButton.Text=="Stop Recording")
                 global recObj
                stop(recObj) % Stop
                
                app.PathTextArea.Value="Recording Stopped .... ";
                app.StartRecordingButton.Text = "Save Audio";
                break;
            end
            if(app.StartRecordingButton.Text=="Save Audio")
                
                 global recObj
                y=getaudiodata(recObj);
                Fs=8000;
                 prompt={'Save As : '}; 
  dlg_title=' Audio File';
  num_lines = [1 50] ; 
  def={''};
  out=inputdlg(prompt,dlg_title,num_lines,def);
  filename=[out{1} '.wav'];
                audiowrite(filename , y,Fs);
                
                 app.PathTextArea.Value="Recording Saved .... ";
                app.StartRecordingButton.Text="Start Recording";
                break;
            end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.Inputaudioaxe);
            cla(app.Fre1);
            cla(app.Fre2);
            cla(app.OutputA);
            app.PathTextArea.Value="";
            app.ValueEditField.Value=0;
            app.Inputaudioaxe.Visible="off";
            app.fft.ImageSource="";
            app.fftshift.ImageSource="";
            app.outputimg.ImageSource="";
            app.InputImage.ImageSource="";
            app.inputimg.ImageSource="";
            app.fft.Visible="off";
            app.fftshift.Visible="off";
            app.outputimg.Visible="off";
            app.inputimg.Visible="off";
            app.InputImage.Visible="off";
            app.Path = 0;
         app.AudPath = 0;
          app. filter=0;
        app.  AudFile=0;
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.fft.Visible="off";
            app.fftshift.Visible="off";
            app.outputimg.Visible="off";
            app.inputimg.Visible="off";
            app.fft.ImageSource="";
            app.fftshift.ImageSource="";
            app.outputimg.ImageSource="";
            app.inputimg.ImageSource="";
            app.DensityEditField.Value=20;
            if exist('InputGray.jpeg','file')==2
                delete('InputGray.jpeg')
             end
              pause(1);
            if exist('FFTImg.jpeg','file')==2
                delete('FFTImg.jpeg')
            end
             pause(1);
            if exist('FsImg.jpeg','file')==2
                delete('FsImg.jpeg')
            end
             pause(1);
            if exist('IFFTImg.jpeg','file')==2
                delete('IFFTImg.jpeg')
            end
            pause(1);
           
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
           
            
            cla(app.Fre1);
            cla(app.Fre2);
            cla(app.OutputA);
             app.ValueEditField.Value=0;
              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.ImageFrame = uipanel(app.UIFigure);
            app.inputimg = uiimage(app.ImageFrame);
            app.fft = uiimage(app.ImageFrame);
            app.fftshift = uiimage(app.ImageFrame);
            app.outputimg = uiimage(app.ImageFrame);
            app.BackButton = uibutton(app.ImageFrame, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.AnalyseImageButton = uibutton(app.ImageFrame, 'push');
            app.AnalyseImageButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseImageButtonPushed, true);
            app.AnalyseImageButton.Text = 'Analyse';
            app.DensityEditFieldLabel = uilabel(app.ImageFrame);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.ImageFrame, 'numeric');
            app.ResetButton_2 = uibutton(app.ImageFrame, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.Noiseframe = uipanel(app.UIFigure);
            app.BackButton_2 = uibutton(app.Noiseframe, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.AnalyseNoiseButton = uibutton(app.Noiseframe, 'push');
            app.AnalyseNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseNoiseButtonPushed, true);
            app.AnalyseNoiseButton.Text = 'Analyse';
            app.ValueEditFieldLabel = uilabel(app.Noiseframe);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.Noiseframe, 'numeric');
            app.FilterButton = uibutton(app.Noiseframe, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.SaveButton = uibutton(app.Noiseframe, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton_3 = uibutton(app.Noiseframe, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.OutputA = uiaxes(app.Noiseframe);
            title(app.OutputA, 'Title')
            xlabel(app.OutputA, 'X')
            ylabel(app.OutputA, 'Y')
            zlabel(app.OutputA, 'Z')
            app.Fre1 = uiaxes(app.Noiseframe);
            title(app.Fre1, 'Title')
            xlabel(app.Fre1, 'X')
            ylabel(app.Fre1, 'Y')
            zlabel(app.Fre1, 'Z')
            app.Fre2 = uiaxes(app.Noiseframe);
            title(app.Fre2, 'Title')
            xlabel(app.Fre2, 'X')
            ylabel(app.Fre2, 'Y')
            zlabel(app.Fre2, 'Z')
            app.MainFrame = uipanel(app.UIFigure);
            app.TextArea = uitextarea(app.MainFrame);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Digital Signal Processing'};
            app.FilteringTypeDropDownLabel = uilabel(app.MainFrame);
            app.FilteringTypeDropDownLabel.Text = 'Filtering Type';
            app.FilteringTypeDropDown = uidropdown(app.MainFrame);
            app.FilteringTypeDropDown.Items = {'Image Processing', 'Noise Flitering'};
            app.FilteringTypeDropDown.ItemsData = {'Image Processing', 'Noise Flitering'};
            app.FilteringTypeDropDown.Value = 'Image Processing';
            app.OpenFileButton = uibutton(app.MainFrame, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.ProcessButton = uibutton(app.MainFrame, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.StartRecordingButton = uibutton(app.MainFrame, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PathTextArea = uitextarea(app.MainFrame);
            app.ResetButton = uibutton(app.MainFrame, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InputImage = uiimage(app.MainFrame);
            app.Inputaudioaxe = uiaxes(app.MainFrame);
            title(app.Inputaudioaxe, 'Audio Signal')
            zlabel(app.Inputaudioaxe, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end